classdef CIE327_Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StatisticalanalysisPanel       matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        ThekurtosisisTextArea          matlab.ui.control.TextArea
        ThekurtosisisTextAreaLabel     matlab.ui.control.Label
        TheskewdnessisTextArea         matlab.ui.control.TextArea
        TheskewdnessisTextAreaLabel    matlab.ui.control.Label
        ThevarianceisTextArea          matlab.ui.control.TextArea
        ThevarianceisTextAreaLabel     matlab.ui.control.Label
        ThemeanisTextArea              matlab.ui.control.TextArea
        ThemeanisTextAreaLabel         matlab.ui.control.Label
        ThemostrepeatedcharctersareTextArea  matlab.ui.control.TextArea
        ThemostrepeatedcharctersareLabel  matlab.ui.control.Label
        DataSelectionLabel             matlab.ui.control.Label
        PleaseenterofmostrepeatedcharactersPanel  matlab.ui.container.Panel
        NumberEditField                matlab.ui.control.NumericEditField
        NumberEditFieldLabel           matlab.ui.control.Label
        PleaseenterfiledirectoryPanel  matlab.ui.container.Panel
        ImportfileButton               matlab.ui.control.Button
        PathEditField                  matlab.ui.control.EditField
        PathEditFieldLabel             matlab.ui.control.Label
        EncodingSwitch                 matlab.ui.control.Switch
        EncodingSwitchLabel            matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        occurences_of_characters
        filter_data
        filter_data_characters
        f_x
        F_x
    end
    
    methods (Access = private)
        function filtered_data = filter(app,data)
            filtered_data=[];
            j=1;% counter for the return array
            isAlphaNum=isstrprop(data,"alphanum");%returns boolean array of alphanum characters in the data array
            for i=1:length(data)
                if isAlphaNum(i)
                        filtered_data(j)= data(i);
                        j=j+1;
                end
            end
        end
        
        
        function results = get_encoded_data(app,data)%return array where the index is the encoded value and the value of each element is the # of occurences
            char_vals=['0','1','2','3','4','5','6','7','8','9','a','A','b','B','c','C','d','D','e','E','f','F','g','G','h','H','i','I','j','J','k','K','l','L','m','M','n','N','o','O','p','P','q','Q','r','R','s','S','t','T','u','U','v','V','w','W','x','X','y','Y','z','Z'];
            results=zeros(1,62);
            for i=1:length(data)
                for j=1:length(char_vals)
                    if data(i)==char_vals(j)
                           results(j)=results(j)+1;
                    end
                end
            end
        end
        function  [sorted_char_array, sorted_array] = get_sorted_array_of_chars_based_on_occurenceArray(app,occurence_array)%returns sorted array of characters based on # of occurences
            sorted_char_array=['0','1','2','3','4','5','6','7','8','9','a','A','b','B','c','C','d','D','e','E','f','F','g','G','h','H','i','I','j','J','k','K','l','L','m','M','n','N','o','O','p','P','q','Q','r','R','s','S','t','T','u','U','v','V','w','W','x','X','y','Y','z','Z'];
            sorted_array=occurence_array;
            [sorted_array,sort_Index] = sort(sorted_array,'descend');
            sorted_char_array = sorted_char_array(sort_Index);            % sort characters using the sorting index
            %for i=1:length(sorted_array)
            %    for j=i:length(sorted_array)
            %        if sorted_array(i)<sorted_array(j)
            %               temp=sorted_array(j);
            %               sorted_array(j)=sorted_array(i);
            %               sorted_array(i)=temp;

            %               temp2=sorted_char_array(j);
            %               sorted_char_array(j)=sorted_char_array(i);
            %               sorted_char_array(i)=temp2;
            %        end
            %    end
            %end
        end
        
        function [m,v,s,k] = get_statistics(app,data)
            f_x=data./sum(data);
            x=1:62;
            x=x-1;
            m = sum(x.*f_x);
            v = sum(((x-m).^2).*f_x);
            %data_p3=sum((data-m).^3);
            s = sum(((x-m).^3).*f_x)/(v^(3/2));
            %data_p4=sum((data-m).^4);
            k = sum(((x-m).^4).*f_x)/(v^2);
        end

        
        function f_x = get_f_x(app,data)
            f_x=data./sum(data);
        end
        
        function F_x = get_F_x(app,f_x)
            F_x=[];
            for i=1:length(f_x)
                F_x(i)=sum(f_x(1:i));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportfileButton
        function ImportfileButtonPushed(app, event)
           [filename,path]=uigetfile('*.txt');
           app.PathEditField.Value=path;
           app.data=fileread(filename);
           app.PathEditField.Value=path;
           %app.draftTextArea.Value=data;
           app.filter_data=filter(app,app.data);
           app.filter_data_characters=char(app.filter_data);
           app.occurences_of_characters=get_encoded_data(app,app.filter_data_characters);
           
           [m,v,s,k]=get_statistics(app,app.occurences_of_characters);
           app.ThemeanisTextArea.Value=string(m);
           app.ThevarianceisTextArea.Value=string(v);
           app.TheskewdnessisTextArea.Value=string(s);
           app.ThekurtosisisTextArea.Value=string(k);
           
           %shift ticks to start at origin and then plot

           app.UIAxes_3.XLim=[1 63];
           app.UIAxes_3.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21';'22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61';'62'};
           app.UIAxes_3.YLim=[0 1];
           app.f_x=get_f_x(app,app.occurences_of_characters);
           stairs(app.UIAxes_3,app.f_x);

           app.UIAxes_2.XLim=[1 63];
           app.UIAxes_2.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21';'22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61';'62'};
           app.UIAxes_2.YLim=[0 1];
           app.F_x=get_F_x(app,app.f_x);
           stairs(app.UIAxes_2,app.F_x);

        end

        % Value changed function: NumberEditField
        function NumberEditFieldValueChanged(app, event)
            limit= length(nonzeros(app.occurences_of_characters)); %do not allow user to ask for more characters than there are in the document if it only has a's and b's then he cannot askk for 3 most repeated
            if (app.NumberEditField.Value>limit)
                app.NumberEditField.Value=limit;
                msgbox(sprintf('There are %d diffrent alphanumeric charcters in the document,\n you cannot chose more than that',limit));
            end
            [uncutmsg,x]=get_sorted_array_of_chars_based_on_occurenceArray(app,app.occurences_of_characters);
            msg=[];
            for i=1:app.NumberEditField.Value
                msg(i)=uncutmsg(i);
            end
            app.ThemostrepeatedcharctersareTextArea.Value=char(msg);
            
        end

        % Value changed function: EncodingSwitch
        function EncodingSwitchValueChanged(app, event)

            if app.EncodingSwitch.Value(1:length('Show characters'))=='Show characters'
                app.UIAxes_3.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; 'a';'A';'b';'B';'c';'C';'d';'D';'e';'E';'f';'F';'g';'G';'h';'H';'i';'I';'j';'J';'k';'K';'l';'L';'m';'M';'n';'N';'o';'O';'p';'P';'q';'Q';'r';'R';'s';'S';'t';'T';'u';'U';'v';'V';'w';'W';'x';'X';'y';'Y';'z';'Z'};
                app.UIAxes_2.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; 'a';'A';'b';'B';'c';'C';'d';'D';'e';'E';'f';'F';'g';'G';'h';'H';'i';'I';'j';'J';'k';'K';'l';'L';'m';'M';'n';'N';'o';'O';'p';'P';'q';'Q';'r';'R';'s';'S';'t';'T';'u';'U';'v';'V';'w';'W';'x';'X';'y';'Y';'z';'Z'};
            else 
                app.UIAxes_3.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21';'22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61';'62'};
                app.UIAxes_2.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21';'22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61';'62'};
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'CDF')
            xlabel(app.UIAxes_2, 'x')
            ylabel(app.UIAxes_2, 'F(x)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 63];
            app.UIAxes_2.YLim = [0 1];
            app.UIAxes_2.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62];
            app.UIAxes_2.XTickLabel = {' '; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'};
            app.UIAxes_2.YTick = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'PMF')
            xlabel(app.UIAxes_3, 'x')
            ylabel(app.UIAxes_3, 'f(x)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XLim = [0 61];
            app.UIAxes_3.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64];
            app.UIAxes_3.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'; '61'; '62'; '63'; '64'};
            app.UIAxes_3.YTick = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.EncodingSwitchLabel = uilabel(app.UIFigure);
            app.EncodingSwitchLabel.Text = 'Encoding';
            app.EncodingSwitch = uiswitch(app.UIFigure, 'slider');
            app.EncodingSwitch.Items = {'Show encoded values', 'Show characters'};
            app.EncodingSwitch.ValueChangedFcn = createCallbackFcn(app, @EncodingSwitchValueChanged, true);
            app.EncodingSwitch.Value = 'Show encoded values';
            app.PleaseenterfiledirectoryPanel = uipanel(app.UIFigure);
            app.PleaseenterfiledirectoryPanel.Title = 'Please enter file directory:';
            app.PathEditFieldLabel = uilabel(app.PleaseenterfiledirectoryPanel);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.PleaseenterfiledirectoryPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.ImportfileButton = uibutton(app.PleaseenterfiledirectoryPanel, 'push');
            app.ImportfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfileButtonPushed, true);
            app.ImportfileButton.Text = 'Import file';
            app.PleaseenterofmostrepeatedcharactersPanel = uipanel(app.UIFigure);
            app.PleaseenterofmostrepeatedcharactersPanel.Title = 'Please enter # of most repeated characters:';
            app.NumberEditFieldLabel = uilabel(app.PleaseenterofmostrepeatedcharactersPanel);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.PleaseenterofmostrepeatedcharactersPanel, 'numeric');
            app.NumberEditField.ValueChangedFcn = createCallbackFcn(app, @NumberEditFieldValueChanged, true);
            app.DataSelectionLabel = uilabel(app.UIFigure);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.StatisticalanalysisPanel = uipanel(app.UIFigure);
            app.StatisticalanalysisPanel.Title = 'Statistical analysis';
            app.ThemostrepeatedcharctersareLabel = uilabel(app.StatisticalanalysisPanel);
            app.ThemostrepeatedcharctersareLabel.Text = {'The most repeated '; 'charcters are:'};
            app.ThemostrepeatedcharctersareTextArea = uitextarea(app.StatisticalanalysisPanel);
            app.ThemostrepeatedcharctersareTextArea.Editable = 'off';
            app.ThemeanisTextAreaLabel = uilabel(app.StatisticalanalysisPanel);
            app.ThemeanisTextAreaLabel.Text = 'The mean is:';
            app.ThemeanisTextArea = uitextarea(app.StatisticalanalysisPanel);
            app.ThevarianceisTextAreaLabel = uilabel(app.StatisticalanalysisPanel);
            app.ThevarianceisTextAreaLabel.Text = 'The variance is:';
            app.ThevarianceisTextArea = uitextarea(app.StatisticalanalysisPanel);
            app.TheskewdnessisTextAreaLabel = uilabel(app.StatisticalanalysisPanel);
            app.TheskewdnessisTextAreaLabel.Text = 'The skewdness is:';
            app.TheskewdnessisTextArea = uitextarea(app.StatisticalanalysisPanel);
            app.ThekurtosisisTextAreaLabel = uilabel(app.StatisticalanalysisPanel);
            app.ThekurtosisisTextAreaLabel.Text = 'The kurtosis is:';
            app.ThekurtosisisTextArea = uitextarea(app.StatisticalanalysisPanel);
            app.Label = uilabel(app.StatisticalanalysisPanel);
            app.Label.Text = '_______________________________________________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIE327_Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end